classdef image_peizhun < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        TabGroup  matlab.ui.container.TabGroup
        Tab       matlab.ui.container.Tab
        UIAxes    matlab.ui.control.UIAxes
        UIAxes3   matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Label     matlab.ui.control.Label
        UIAxes4   matlab.ui.control.UIAxes
        UITable   matlab.ui.control.Table
        Tab_2     matlab.ui.container.Tab
        UIAxes5   matlab.ui.control.UIAxes
        UIAxes6   matlab.ui.control.UIAxes
        Button_4  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global  master_image shift_image;
            imshow(master_image ,'Parent',app.UIAxes)
            imshow(shift_image ,'Parent',app.UIAxes4)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global master_image shift_image window_1 window_2 matirx tform;
            window_1=master_image;
            window_2=shift_image;
            points_left=detectSURFFeatures(window_1);
            points_right=detectSURFFeatures(window_2);
            %提取特征点
            [features_left,vector_points_left]=extractFeatures(window_1,points_left);
            [features_right,vector_points_right]=extractFeatures(window_1,points_right);
            %检索特征点位置
            indexPairs=matchFeatures(features_left,features_right);
            matchedPoints_left=vector_points_left(indexPairs(:,1),:);
            matchedPoints_right=vector_points_right(indexPairs(:,2),:);
            showMatchedFeatures(window_1,window_2,matchedPoints_left,matchedPoints_right,'Parent',app.UIAxes3);
            % 排除异常值，并计算转换矩阵。
            [tform,~,~]=estimateGeometricTransform(matchedPoints_right,matchedPoints_left,'similarity');
            matirx=tform.T;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global window_1 window_2   tform Ir uint8_image2
            outputView=imref2d(size(window_1));
            Ir=imwarp(window_2,tform,'outputview',outputView);                
            imshow(uint8_image2,'Parent',app.UIAxes5);
            imshow(Ir,'Parent',app.UIAxes6);         
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global matirx
            app.UITable.Data=matirx;
        end

        % Callback function
        function Button_5Pushed(app, event)
            global rmse_frontde meapde Ir  uint8_image2 
            rmse_end25=sqrt(mean((Ir-uint8_image2).^2));
            nvg25=length(rmse_end25);
            rmse_endde25=sum(rmse_end25)/nvg25;
            app.EditField.Value=rmse_frontde;
            app.EditField_2.Value=strcat(num2str(rmse_endde25));
            meap_end25=mean(abs((Ir- uint8_image2)./Ir))*100;
            cvg25=length(meap_end25);
            meap_endde25=sum( meap_end25)/cvg25;
            app.EditField_3.Value=strcat(num2str(meapde));
            app.EditField_4.Value=strcat(num2str(meap_endde25));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '计算步骤';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '主影像显示')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, '特征点连接')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '绘制显示主辅影像';
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '计算特征点';
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '计算转换矩阵';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '转换矩阵';
            app.UIAxes4 = uiaxes(app.Tab);
            title(app.UIAxes4, '辅图像显示')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '结果精度显示';
            app.UIAxes5 = uiaxes(app.Tab_2);
            title(app.UIAxes5, '影像二未进行配准图')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes6 = uiaxes(app.Tab_2);
            title(app.UIAxes6, '影像二粗配准以及精确配准后的图')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.Button_4 = uibutton(app.Tab_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '查看最终配准效果';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_peizhun

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end